classdef AppInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FeatureSelectionDropDownLabel   matlab.ui.control.Label
        LoadDataDropDownLabel           matlab.ui.control.Label
        LoadDataDropDown                matlab.ui.control.DropDown
        Panel                           matlab.ui.container.Panel
        CriteriaDropDownLabel           matlab.ui.control.Label
        CriteriaDropDown                matlab.ui.control.DropDown
        ThefeaturesrelevanceisTextAreaLabel  matlab.ui.control.Label
        ThefeaturesrelevanceisTextArea  matlab.ui.control.TextArea
        FeatureSelectionDropDown        matlab.ui.control.DropDown
        OKButton                        matlab.ui.control.StateButton
        TheRedundantFeaturesareTextAreaLabel  matlab.ui.control.Label
        TheRedundantFeaturesareTextArea  matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Callback function
        function testeText(app, event)
            value = app.Button.Value;
            app.ThefeaturesrelevanceisTextArea.Value=parte2()
            app.Panel.Visible='on';
        end

        % Value changed function: OKButton
        function FeatureReduct(app, event)
            state = app.LoadDataDropDown.Value;
            if isequal(state,"Australia")
                load("data_struct_v2.mat")
            elseif isequal(state,"New South Wales")
                load("dataNewSouthWalles.mat")
                data=dataNewSouthWalles;
            elseif isequal(state,"Victoria")
                load("dataVictoria.mat")
                data=dataVictoria;
            elseif isequal(state,"Northen Territory")
                load("dataNT.mat")
                data=dataNT;
            elseif isequal(state,"Tasmania")
                load("dataTasmania.mat")
                data=dataTasmania;
            elseif isequal(state,"South Australia")
                load("dataSouthAustralia.mat")
                data=dataSouthAustralia
            elseif isequal(state,"Norfolk Island")
                load("dataNorfolkIsland.mat")
                data=dataNorfolkIsland;
            elseif isequal(state,"Western Australia")
                load("dataWesternAustralia.mat")
                data=dataWesternAustralia;
            elseif isequal(state,"Queen Island")
                load("dataQueenIsland.mat")
                data=dataQueenIsland;
            end   
            featSel=app.FeatureSelectionDropDown.Value;
            data=scalestd(data)
            
            if isequal(featSel,"PCA")
                criteria=app.CriteriaDropDown.Value;
                PrincipalComponentAnalysis(data,criteria)
            elseif isequal(featSel,"LDA")
                LDAReductionTo1D(data)
            elseif isequal(featSel,"Kruskall Wallis")
                load("Data_final.mat")
                col_names=string(T_final.Properties.VariableNames);
                col_names=col_names(3:end);
                [txt,red]=KruskalWallisRanking(data,col_names)
                app.ThefeaturesrelevanceisTextArea.Value=strcat(txt);
                app.TheRedundantFeaturesareTextArea.Value=strcat(red);
            end   
       
        end

        % Value changed function: FeatureSelectionDropDown
        function showAttributes(app, event)
            value = app.FeatureSelectionDropDown.Value;
            if isequal(value,"PCA")
                app.Panel.Visible='on';
            else
                app.Panel.Visible='off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FeatureSelectionDropDownLabel = uilabel(app.UIFigure);
            app.FeatureSelectionDropDownLabel.Text = 'Feature Selection';
            app.FeatureSelectionDropDown = uidropdown(app.UIFigure);
            app.FeatureSelectionDropDown.Items = {'PCA', 'LDA', 'Kruskall Wallis'};
            app.FeatureSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @showAttributes, true);
            app.FeatureSelectionDropDown.Value = 'PCA';
            app.LoadDataDropDownLabel = uilabel(app.UIFigure);
            app.LoadDataDropDownLabel.Text = 'Load Data';
            app.LoadDataDropDown = uidropdown(app.UIFigure);
            app.LoadDataDropDown.Items = {'Australia', 'New South Wales', 'South Australia', 'Victoria', 'Tasmania', 'Norfolk Island', 'Northen Territory', 'Western Australia', 'Queen Island'};
            app.LoadDataDropDown.Value = 'Australia';
            app.Panel = uipanel(app.UIFigure);
            app.CriteriaDropDownLabel = uilabel(app.Panel);
            app.CriteriaDropDownLabel.Text = 'Criteria';
            app.CriteriaDropDown = uidropdown(app.Panel);
            app.CriteriaDropDown.Items = {'Scree', 'Kaiser'};
            app.CriteriaDropDown.Value = 'Scree';
            app.ThefeaturesrelevanceisTextAreaLabel = uilabel(app.UIFigure);
            app.ThefeaturesrelevanceisTextAreaLabel.Text = 'The features relevance is :';
            app.ThefeaturesrelevanceisTextArea = uitextarea(app.UIFigure);
            app.OKButton = uibutton(app.UIFigure, 'state');
            app.OKButton.ValueChangedFcn = createCallbackFcn(app, @FeatureReduct, true);
            app.OKButton.Text = 'OK';
            app.TheRedundantFeaturesareTextAreaLabel = uilabel(app.UIFigure);
            app.TheRedundantFeaturesareTextAreaLabel.Text = 'The Redundant Features are: ';
            app.TheRedundantFeaturesareTextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = AppInterface

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end